classdef Get_Paths_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        PathtotsvfileLabel  matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        EditField           matlab.ui.control.EditField
        SetButton           matlab.ui.control.Button
        GridLayout4         matlab.ui.container.GridLayout
        PathtometafilejpkforcemaporbhlfLabel  matlab.ui.control.Label
        GridLayout5         matlab.ui.container.GridLayout
        EditField_2         matlab.ui.control.EditField
        SetButton_2         matlab.ui.control.Button
        GridLayout6         matlab.ui.container.GridLayout
        SubmitButton        matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        tsv_path_old = '' % Path to the tsv file
        meta_path_old = '' % Path to the meta file
        tsv_path_new = '';  % New path to the tsv file.
        meta_path_new = ''; % New path to the meta file.
        main_app = [];  % Object of the main GUI invoking this GUI.
        wait_status = 1; % Status for the main GUI calling this sub-GUI to wait 
        canceled = 0 % Is set to 1 if GUI is forced closed.
    end
        
    methods (Access = public)
        
        function display_previous_paths(app,tsv_path,meta_path)
            app.tsv_path_old = tsv_path;
            app.meta_path_old = meta_path;
            app.EditField.Value = tsv_path;
            app.EditField_2.Value = meta_path;
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tsv_path, meta_path)
            app.tsv_path_old = tsv_path;
            app.meta_path_old = meta_path;
            app.EditField.Value = tsv_path;
            app.EditField_2.Value = meta_path;
%             app.main_app = app2;
%             main_app_pos = app.main_app.Hist_GUI_UIFigure.Position;
%             this_app_pos = app.UIFigure.Position;
%             app.UIFigure.Position([1 2]) = [main_app_pos(3)/2-this_app_pos(3)/2 main_app_pos(4)/2-this_app_pos(4)/2];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~strcmp(event,'submitButton')
                app.canceled = 1;
            end
            
            app.wait_status = 0; 
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            [tsv_path,~,~] = fileparts(app.tsv_path_old);
            [selected_f,selected_p] = uigetfile('*.tsv','Please select a tsv file',tsv_path);
            if isequal(selected_f,0) || isequal(selected_p,0)                
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.tsv_path_new = selected_file;
            app.EditField.Value = selected_file;
            app.EditField.Tooltip = selected_file;
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            [meta_path,~,~] = fileparts(app.meta_path_old);
            [selected_f,selected_p] = uigetfile({'*.bhlf; *.jpk-force-map','Meta data files (*.bhlf, *.jpk-force-map)'},'Please select a meta data file',meta_path);
            if isequal(selected_f,0) || isequal(selected_p,0)
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.meta_path_new = selected_file;
            app.EditField_2.Value = selected_file;
            app.EditField_2.Tooltip = selected_file;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField.Value = app.tsv_path_old;
            app.EditField_2.Value = app.meta_path_old;
            app.tsv_path_new = '';
            app.meta_path_new = '';
            app.EditField.Tooltip = app.tsv_path_old;
            app.EditField_2.Tooltip = app.meta_path_old;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % error handling for callback
            if isempty(app.EditField.Value)
                errordlg('tsv file path is empty -> You must set the tsv file path!','tsv file path error','modal');
                return;
            end
            if isempty(app.EditField_2.Value)
                errordlg('meta data file path is empty -> You must set the meta data file path!','meta data file path error','modal');
                return;
            end
            if ~isfile(app.EditField.Value)
                error('The chosen tsv file does not exist!');
            end
            if ~isfile(app.EditField_2.Value)
                error('The chosen meta data file does not exist!');
            end
            
                        
            % main code of callback
            if isempty(app.tsv_path_new)
                app.tsv_path_new = app.tsv_path_old;
            end
            if isempty(app.meta_path_new)
                app.meta_path_new = app.meta_path_old;
            end
            app.UIFigure.WindowState = 'minimized';
            % call the close request callback where the edit field values
            % are given to the invoking main GUI and, afterward, this GUI is closed.
            UIFigureCloseRequest(app,'submitButton')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PathtotsvfileLabel = uilabel(app.GridLayout2);
            app.PathtotsvfileLabel.Text = 'Path to .tsv file:';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Editable = 'off';
            app.SetButton = uibutton(app.GridLayout3, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PathtometafilejpkforcemaporbhlfLabel = uilabel(app.GridLayout4);
            app.PathtometafilejpkforcemaporbhlfLabel.Text = 'Path to meta file (.jpk-force-map or .bhlf):';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.EditField_2 = uieditfield(app.GridLayout5, 'text');
            app.EditField_2.Editable = 'off';
            app.SetButton_2 = uibutton(app.GridLayout5, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SubmitButton = uibutton(app.GridLayout6, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Get_Paths_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end