classdef Refine_BM_Mask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Refine_BM_Mask_AppUIFigure  matlab.ui.Figure
        MainGUIGrid                 matlab.ui.container.GridLayout
        GridLayout_Arrow            matlab.ui.container.GridLayout
        Label_Arrow                 matlab.ui.control.Label
        ApplyMaskButton             matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        GridLayouRightImage         matlab.ui.container.GridLayout
        RefinedMaskLabel            matlab.ui.control.Label
        UIAxes_rightImage           matlab.ui.control.UIAxes
        GridLayoutLeftImage         matlab.ui.container.GridLayout
        InitialMaskLabel            matlab.ui.control.Label
        UIAxes_leftImage            matlab.ui.control.UIAxes
        GetInitialBMMaskFromPanel   matlab.ui.container.Panel
        GetInitialMaskPanelGrid     matlab.ui.container.GridLayout
        ofimagevaluesLabel          matlab.ui.control.Label
        EditField_Percent           matlab.ui.control.NumericEditField
        DropDown_top_lower          matlab.ui.control.DropDown
        UseLabel                    matlab.ui.control.Label
        UserdefinedmaskLabel        matlab.ui.control.Label
        CheckBox_MaskByLimit        matlab.ui.control.CheckBox
        CheckBox_UserDefinedMask    matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        CloseRequest = 0; % Indicates if the user wants to close the app.
        Canceled = 0; % Indicates fi the user canceled the application of the filter.
        afm_darkgold = []; % AFM_Darkgold colormap. Is loaded in the sartupFcn.
        Image_Data = []; % Data of the displayed map image.
    end
    
    methods (Access = public)
        
        function DisplayImage(app,targetAx,ImData)
            mu = mean(ImData,"all","omitnan");
            sigma = std(ImData,0,"all","omitnan");
            cbar_range = sigma*2.2;
            cbar_mean = mu+(0.9*sigma);
            clim_min = cbar_mean - cbar_range;
            clim_max = cbar_mean + cbar_range;
            cla(targetAx);
            imagesc(targetAx,'CData',ImData,"Tag","Map_Image");
            colormap(targetAx,app.afm_darkgold);
            clim(targetAx,[clim_min clim_max]);
            targetAx.PlotBoxAspectRatio = [1 1 1];
            axis(targetAx,'tight');
        end
        
        function ActivateCheckbox(app,CheckNum)
            switch CheckNum
                case 1
                    app.CheckBox_UserDefinedMask.Value = 1;
    	            app.CheckBox_MaskByLimit.Value = 0;
                    % Function to define new initial mask
                case 2
                    app.CheckBox_MaskByLimit.Value = 1;
                    app.CheckBox_UserDefinedMask.Value = 0;
                    % Function to define new initial mask
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Top_Image, Curr_Mask, Settings)

            % Set position of this application.
            Pos_MainGUI = Settings.MainGUIPosition;
            GUIHeight = int32(Pos_MainGUI(4).*0.8);
            GUIWidth = int32(app.Refine_BM_Mask_AppUIFigure.Position(3) .* (double(GUIHeight)./app.Refine_BM_Mask_AppUIFigure.Position(4)));
            xGUI = Pos_MainGUI(1) + (Pos_MainGUI(3)/2) - (GUIWidth/2);
            yGUI = Pos_MainGUI(2) + (Pos_MainGUI(4)/2) - (GUIHeight/2);
            app.Refine_BM_Mask_AppUIFigure.Position = [xGUI yGUI GUIWidth GUIHeight];

            % Write Properties
            app.Image_Data = Top_Image;
            %%%%% ---> CONTINUE HERE <--- %%%%%

            % Display map image in both axes using the afm_darkgold colormap
            S = load("afm_darkgold.mat");
            app.afm_darkgold = S.afm_darkgold;
            DisplayImage(app,app.UIAxes_leftImage,Top_Image);
            DisplayImage(app,app.UIAxes_rightImage,Top_Image);

            % Activate mask option if a current mask is available
            if ~isempty(Curr_Mask)
                app.CheckBox_UserDefinedMask.Enable = "on";
                app.UserdefinedmaskLabel.Enable = "on";
                ActivateCheckbox(app,1)
                DisplayMask
            else
                ActivateCheckbox(app,2)
            end
            % keyboard;
            
        end

        % Close request function: Refine_BM_Mask_AppUIFigure
        function Refine_BM_Mask_AppUIFigureCloseRequest(app, event)
            app.Canceled = 1;
            app.CloseRequest = 1;            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Canceled = 1;
            app.CloseRequest = 1;
        end

        % Button pushed function: ApplyMaskButton
        function ApplyMaskButtonPushed(app, event)
            
            app.Canceled = 0;
            app.CloseRequest = 1;
        end

        % Value changed function: CheckBox_UserDefinedMask
        function CheckBox_UserDefinedMaskValueChanged(app, event)
            value = app.CheckBox_UserDefinedMask.Value;
            switch value
                case 1
                    ActivateCheckbox(app,1)
                case 0
                    app.CheckBox_UserDefinedMask.Value = 1;
            end
        end

        % Value changed function: CheckBox_MaskByLimit
        function CheckBox_MaskByLimitValueChanged(app, event)
            value = app.CheckBox_MaskByLimit.Value;
            switch value
                case 1
                    ActivateCheckbox(app,2)
                case 0
                    app.CheckBox_MaskByLimit.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Refine_BM_Mask_AppUIFigure = uifigure('Visible', 'off');
            app.Refine_BM_Mask_AppUIFigure.Name = 'Refine_BM_Mask_App';
            app.Refine_BM_Mask_AppUIFigure.CloseRequestFcn = createCallbackFcn(app, @Refine_BM_Mask_AppUIFigureCloseRequest, true);
            app.MainGUIGrid = uigridlayout(app.Refine_BM_Mask_AppUIFigure);
            app.GetInitialBMMaskFromPanel = uipanel(app.MainGUIGrid);
            app.GetInitialBMMaskFromPanel.Title = 'Get Initial BM Mask From';
            app.GetInitialMaskPanelGrid = uigridlayout(app.GetInitialBMMaskFromPanel);
            app.CheckBox_UserDefinedMask = uicheckbox(app.GetInitialMaskPanelGrid);
            app.CheckBox_UserDefinedMask.ValueChangedFcn = createCallbackFcn(app, @CheckBox_UserDefinedMaskValueChanged, true);
            app.CheckBox_UserDefinedMask.Enable = 'off';
            app.CheckBox_UserDefinedMask.Text = '';
            app.CheckBox_MaskByLimit = uicheckbox(app.GetInitialMaskPanelGrid);
            app.CheckBox_MaskByLimit.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MaskByLimitValueChanged, true);
            app.CheckBox_MaskByLimit.Text = '';
            app.CheckBox_MaskByLimit.Value = true;
            app.UserdefinedmaskLabel = uilabel(app.GetInitialMaskPanelGrid);
            app.UserdefinedmaskLabel.Enable = 'off';
            app.UserdefinedmaskLabel.Text = 'User defined mask';
            app.UseLabel = uilabel(app.GetInitialMaskPanelGrid);
            app.UseLabel.Text = 'Use';
            app.DropDown_top_lower = uidropdown(app.GetInitialMaskPanelGrid);
            app.DropDown_top_lower.Items = {'top', 'lower'};
            app.DropDown_top_lower.Value = 'top';
            app.EditField_Percent = uieditfield(app.GetInitialMaskPanelGrid, 'numeric');
            app.EditField_Percent.Limits = [0 100];
            app.EditField_Percent.ValueDisplayFormat = '%.0f';
            app.EditField_Percent.Value = 30;
            app.ofimagevaluesLabel = uilabel(app.GetInitialMaskPanelGrid);
            app.ofimagevaluesLabel.Text = '% of image values';
            app.GridLayoutLeftImage = uigridlayout(app.MainGUIGrid);
            app.UIAxes_leftImage = uiaxes(app.GridLayoutLeftImage);
            app.UIAxes_leftImage.XTick = [];
            app.UIAxes_leftImage.YTick = [];
            app.UIAxes_leftImage.ZTick = [];
            app.InitialMaskLabel = uilabel(app.GridLayoutLeftImage);
            app.InitialMaskLabel.Text = 'Initial Mask';
            app.GridLayouRightImage = uigridlayout(app.MainGUIGrid);
            app.UIAxes_rightImage = uiaxes(app.GridLayouRightImage);
            app.UIAxes_rightImage.XTick = [];
            app.UIAxes_rightImage.YTick = [];
            app.UIAxes_rightImage.ZTick = [];
            app.RefinedMaskLabel = uilabel(app.GridLayouRightImage);
            app.RefinedMaskLabel.Text = 'Refined Mask';
            app.CancelButton = uibutton(app.MainGUIGrid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyMaskButton = uibutton(app.MainGUIGrid, 'push');
            app.ApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaskButtonPushed, true);
            app.ApplyMaskButton.Text = 'Apply Mask';
            app.GridLayout_Arrow = uigridlayout(app.MainGUIGrid);
            app.Label_Arrow = uilabel(app.GridLayout_Arrow);
            app.Label_Arrow.Interpreter = 'html';
            app.Label_Arrow.Text = '&#10151;';
            % Show the figure after all components are created
            app.Refine_BM_Mask_AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Refine_BM_Mask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Refine_BM_Mask_AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Refine_BM_Mask_AppUIFigure)
        end
    end
end